classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        KLASIFIKASIDAUNLabel       matlab.ui.control.Label
        InsertGambarButton         matlab.ui.control.Button
        EkstraksiFiturButton       matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        KlasifikasiEditFieldLabel  matlab.ui.control.Label
        KlasifikasiEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: InsertGambarButton
        function InsertGambarButtonPushed(app, event)
            ax = uiaxes(app.UIFigure,'Position',[0 270 300 400]);
              cx= uiaxes(app.UIFigure,'Position',[280 270 300 400]);
              bx= uiaxes(app.UIFigure,'Position',[550 270 300 400]);
             
              image(img,'Parent',ax);
              gray = repmat(im2uint8(gray),1,1,3);
              image(gray,'Parent',cx);
              rgb = repmat(im2uint8(clo),1,1,3);
              image(rgb,'Parent',bx);
              r = imhist(img);
              g = imhist(gray);
              b = imhist(rgb);
              plot(app.rgb,r,'-r');
              plot(app.gray,g,'-r');
              plot(app.bin,b,'-r');
              set(ax,'visible','off');
              set(bx,'visible','off');
              set(cx,'visible','off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Citra Asli')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Gray Scale')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Binary')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.KLASIFIKASIDAUNLabel = uilabel(app.UIFigure);
            app.KLASIFIKASIDAUNLabel.Text = 'KLASIFIKASI DAUN';
            app.InsertGambarButton = uibutton(app.UIFigure, 'push');
            app.InsertGambarButton.ButtonPushedFcn = createCallbackFcn(app, @InsertGambarButtonPushed, true);
            app.InsertGambarButton.Text = 'Insert Gambar';
            app.EkstraksiFiturButton = uibutton(app.UIFigure, 'push');
            app.EkstraksiFiturButton.Text = 'Ekstraksi Fitur';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Area'; 'Major Axis'; 'Minor Axis'; 'Perimeter'; 'Ecentricity'};
            app.UITable.RowName = {};
            app.KlasifikasiEditFieldLabel = uilabel(app.UIFigure);
            app.KlasifikasiEditFieldLabel.Text = 'Klasifikasi:';
            app.KlasifikasiEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end